classdef CV_Zurich_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CapacitanceVoltageCVMFIAZurichTab  matlab.ui.container.Tab
        MFIAZIConnectionStatusLamp      matlab.ui.control.Lamp
        MFIAZIConnectionStatusLampLabel  matlab.ui.control.Label
        SaveDataAdditionalCommentButton  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorStatusLamp             matlab.ui.control.Lamp
        IndicatorStatusLampLabel        matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        MeasurementParametersPanel      matlab.ui.container.Panel
        bandwidthoverlapEditField       matlab.ui.control.NumericEditField
        bandwidthoverlapEditFieldLabel  matlab.ui.control.Label
        bandwidthcontrolEditField       matlab.ui.control.NumericEditField
        bandwidthcontrolEditFieldLabel  matlab.ui.control.Label
        averaging_sampleEditField       matlab.ui.control.NumericEditField
        averaging_sampleEditFieldLabel  matlab.ui.control.Label
        averaging_tcEditField           matlab.ui.control.NumericEditField
        averaging_tcEditFieldLabel      matlab.ui.control.Label
        sweep_inaccuracyEditField       matlab.ui.control.NumericEditField
        sweep_inaccuracyEditFieldLabel  matlab.ui.control.Label
        xmappingEditField               matlab.ui.control.NumericEditField
        xmappingEditFieldLabel          matlab.ui.control.Label
        loop_countEditField             matlab.ui.control.NumericEditField
        loop_countEditFieldLabel        matlab.ui.control.Label
        sweep_samplecountEditField      matlab.ui.control.NumericEditField
        sweep_samplecountEditFieldLabel  matlab.ui.control.Label
        sweep_mode_scanEditField        matlab.ui.control.NumericEditField
        sweep_mode_scanEditFieldLabel   matlab.ui.control.Label
        stop_voltageEditField           matlab.ui.control.NumericEditField
        stop_voltageEditFieldLabel      matlab.ui.control.Label
        start_voltageEditField          matlab.ui.control.NumericEditField
        start_voltageEditFieldLabel     matlab.ui.control.Label
        DeviceSettingsPanel             matlab.ui.container.Panel
        imps_modelEditField             matlab.ui.control.NumericEditField
        imps_modelEditFieldLabel        matlab.ui.control.Label
        imps_modeEditField              matlab.ui.control.NumericEditField
        imps_modeEditFieldLabel         matlab.ui.control.Label
        imps_freqEditField              matlab.ui.control.NumericEditField
        imps_freqEditFieldLabel         matlab.ui.control.Label
        imps_amplitudeEditField         matlab.ui.control.NumericEditField
        imps_amplitudeEditFieldLabel    matlab.ui.control.Label
        imps_channelEditField           matlab.ui.control.NumericEditField
        imps_channelEditFieldLabel      matlab.ui.control.Label
        osc_cEditField                  matlab.ui.control.NumericEditField
        osc_cEditFieldLabel             matlab.ui.control.Label
        osc_freqEditField               matlab.ui.control.NumericEditField
        osc_freqEditFieldLabel          matlab.ui.control.Label
        demod_tcEditField               matlab.ui.control.NumericEditField
        demod_tcEditFieldLabel          matlab.ui.control.Label
        demod_enableEditField           matlab.ui.control.NumericEditField
        demod_enableEditFieldLabel      matlab.ui.control.Label
        demod_harmonicEditField         matlab.ui.control.NumericEditField
        demod_harmonicEditFieldLabel    matlab.ui.control.Label
        demod_rateEditField             matlab.ui.control.NumericEditField
        demod_rateEditFieldLabel        matlab.ui.control.Label
        demod_cEditField                matlab.ui.control.NumericEditField
        demod_cEditFieldLabel           matlab.ui.control.Label
        demod_orderEditField            matlab.ui.control.NumericEditField
        demod_orderEditFieldLabel       matlab.ui.control.Label
        demod_phaseshiftEditField       matlab.ui.control.NumericEditField
        demod_phaseshiftEditFieldLabel  matlab.ui.control.Label
        sigout_mixer_enableEditField    matlab.ui.control.NumericEditField
        sigout_mixer_enableEditFieldLabel  matlab.ui.control.Label
        sigout_amplitudeEditField       matlab.ui.control.NumericEditField
        sigout_amplitudeEditFieldLabel  matlab.ui.control.Label
        sigout_rangeEditField           matlab.ui.control.NumericEditField
        sigout_rangeEditFieldLabel      matlab.ui.control.Label
        sigout_onEditField              matlab.ui.control.NumericEditField
        sigout_onEditFieldLabel         matlab.ui.control.Label
        input_rangeEditField            matlab.ui.control.NumericEditField
        input_rangeEditFieldLabel       matlab.ui.control.Label
        input_acEditField               matlab.ui.control.NumericEditField
        input_acEditFieldLabel          matlab.ui.control.Label
        out_cEditField                  matlab.ui.control.NumericEditField
        out_cEditFieldLabel             matlab.ui.control.Label
        input_imp50EditField            matlab.ui.control.NumericEditField
        input_imp50EditFieldLabel       matlab.ui.control.Label
        in_cEditField                   matlab.ui.control.NumericEditField
        in_cEditFieldLabel              matlab.ui.control.Label
        DefaultFolderPanel              matlab.ui.container.Panel
        SelectFolderButton              matlab.ui.control.Button
        DefaultFolderEditField          matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        CVZurichInstrumentsMFIALabel    matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        MFIAPropertiesPanel             matlab.ui.container.Panel
        Discovery_ID_Textarea           matlab.ui.control.TextArea
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        DelaySTextArea                  matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        MeasurementWindowLabel          matlab.ui.control.Label
        DelaySTextAreaLabel             matlab.ui.control.Label
        CalculatedPointsTextArea        matlab.ui.control.TextArea
        SweepPointsTextArea             matlab.ui.control.TextArea
        StepTextArea                    matlab.ui.control.TextArea
        CalculatedPointsTextArea_2Label  matlab.ui.control.Label
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        CalculatedPointsLabel_2         matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        CV_Plot_Linear                  matlab.ui.control.UIAxes
        CV_Plot_Logarithmic             matlab.ui.control.UIAxes
    end

        properties (Access = private)
            SampleInfo;
            Comments;
            additionalComments = 'No additional comments';
            additionalCommentsCell;
            currentDateTime;
            hourvalue;
            minutevalue;
            secondvalue;
            yearvalue;
            monthvalue;
            dayvalue;
            Combineddate;
            dateString;
            Combinedtime;
            timeString;
        
            device_id = 'dev5478';
            device;
            props;
            connectionStatusMFIA = 'Not Connected';
            branches;
            out_mixer_c;
            stopExperiment = false;
        
            data;
            sheet1 = 'Settings';
            sheet2 = 'Measurements';
            settings;
            measurement_table;
            measurement_var_names;
            consttableData;
            defaultFolder;
        
            voltage_source;
            capacitance_measure;
        
            in_c;
            out_c;
            input_imp50;
            input_ac;
            input_range;
            sigout_on;
            sigout_range;
            sigout_amplitude;
            sigout_mixer_enable;
            demod_phaseshift;
            demod_order;
            demod_c;
            demod_rate;
            demod_harmonic;
            demod_enable;
            demod_tc;
            osc_c;
            osc_freq;
            imps_channel;
            imps_amplitude;
            imps_freq;
            imps_mode;
            imps_model;
        
            start_voltage;
            stop_voltage;
            sweep_mode_scan;
            sweep_samplecount;
            sweep_inaccuracy;
            xmapping;
            loopcount;
            averaging_tc;
            averaging_sample;
            bandwidthcontrol;
            bandwidthoverlap;
        end
    
    methods (Access = public)
        function connectDeviceZurich(app)
            % Reset any previous ziDAQ session before opening a new one.
            if strcmp(app.connectionStatusMFIA, 'Connected')
                clear ziDAQ;
                app.connectionStatusMFIA = 'Not Connected';
            end
    
            try
                [app.device, app.props] = ziCreateAPISession(app.device_id, 6);
                ziDAQ('connect', '127.0.0.1', 8006, 6);
                ziDAQ('connectDevice', app.device_id, 'PCIe');
    
                app.MFIAZIConnectionStatusLamp.Color = 'g';
                app.connectionStatusMFIA = 'Connected';
            catch
                app.MFIAZIConnectionStatusLamp.Color = 'r';
                app.IndicatorStatusLamp.Color = 'r';
                app.StateoftheExperimentTextArea.Value = {'Connection failed!'};
                app.connectionStatusMFIA = 'Not Connected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Restore the previously used save folder.
            userBase = userpath;
            if isempty(userBase)
                userBase = pwd;
            elseif contains(userBase, pathsep)
                userBase = extractBefore(userBase, pathsep);
            end
    
            defaultFallback = fullfile(strtrim(userBase), 'MyAppData');
            if ~isfolder(defaultFallback)
                mkdir(defaultFallback);
            end
    
            app.DefaultFolderEditField.Value = getpref('MyApp', 'DataFolder', defaultFallback);
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization
            format longE
            
            app.defaultFolder = app.DefaultFolderEditField.Value;
            app.stopExperiment = false;
            app.additionalComments = 'No additional comments';
            
            app.connectDeviceZurich();
            ziDisableEverything(app.device_id);
            
            if ~strcmp(app.connectionStatusMFIA, 'Connected')
                uialert(app.UIFigure, ...
                    'Cannot start the experiment because the device is not connected', ...
                    'Connection Error')
                return;
            end
            
            app.IndicatorStatusLamp.Color = 'y';
            app.StateoftheExperimentTextArea.Value = {'Initializing parameters and settings'};
            app.StartExperimentButton.Enable = "off";
            
            cla(app.CV_Plot_Linear)
            cla(app.CV_Plot_Logarithmic)
            app.MeasurementTable.Data = [];
            app.MeasurementTable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.ConstantValuesTabel.Data = [];
            
            app.SampleInfo = app.SampleInfoEditField.Value;
            app.Comments = app.CommentsEditField.Value;
            
            app.in_c = app.in_cEditField.Value;
            app.out_c = app.out_cEditField.Value;
            app.input_imp50 = app.input_imp50EditField.Value;
            app.input_ac = app.input_acEditField.Value;
            app.input_range = app.input_rangeEditField.Value;
            app.sigout_on = app.sigout_onEditField.Value;
            app.sigout_range = app.sigout_rangeEditField.Value;
            app.sigout_amplitude = app.sigout_amplitudeEditField.Value;
            app.sigout_mixer_enable = app.sigout_mixer_enableEditField.Value;
            app.demod_phaseshift = app.demod_phaseshiftEditField.Value;
            app.demod_order = app.demod_orderEditField.Value;
            app.demod_c = app.demod_cEditField.Value;
            app.demod_rate = app.demod_rateEditField.Value;
            app.demod_harmonic = app.demod_harmonicEditField.Value;
            app.demod_enable = app.demod_enableEditField.Value;
            app.demod_tc = app.demod_tcEditField.Value;
            app.osc_c = app.osc_cEditField.Value;
            app.osc_freq = app.osc_freqEditField.Value;
            app.imps_channel = app.imps_channelEditField.Value;
            app.imps_amplitude = app.imps_amplitudeEditField.Value;
            app.imps_freq = app.imps_freqEditField.Value;
            app.imps_mode = app.imps_modeEditField.Value;
            app.imps_model = app.imps_modelEditField.Value;
            
            app.start_voltage = app.start_voltageEditField.Value;
            app.stop_voltage = app.stop_voltageEditField.Value;
            app.sweep_mode_scan = app.sweep_mode_scanEditField.Value;
            app.sweep_samplecount = app.sweep_samplecountEditField.Value;
            app.sweep_inaccuracy = app.sweep_inaccuracyEditField.Value;
            app.xmapping = app.xmappingEditField.Value;
            app.loopcount = app.loop_countEditField.Value;
            app.averaging_tc = app.averaging_tcEditField.Value;
            app.averaging_sample = app.averaging_sampleEditField.Value;
            app.bandwidthcontrol = app.bandwidthcontrolEditField.Value;
            app.bandwidthoverlap = app.bandwidthoverlapEditField.Value;
            
            json_str = jsonencode(ziDAQ('discoveryGet', app.device_id), 'PrettyPrint', true);
            app.branches = ziDAQ('listNodes', ['/' app.device_id], 0);
            app.Discovery_ID_Textarea.Value = [splitlines(json_str); {'--- Node List ---'}; app.branches(:)];
            app.out_mixer_c = str2double(ziGetDefaultSigoutMixerChannel(app.props, app.out_c));
            
            %% Parameters
            app.sheet1 = 'Settings';
            app.sheet2 = 'Measurements';
            
            app.currentDateTime = datetime('now');
            app.dateString = char(string(app.currentDateTime, 'dd.MM.yyyy'));
            app.timeString = char(string(app.currentDateTime, 'HH:mm:ss'));
            
            app.voltage_source = nan(app.sweep_samplecount, 1);
            app.capacitance_measure = nan(app.sweep_samplecount, 1);
            
            app.settings = {
            'Time',  '';
            'Date',  app.dateString;
            'Clock', app.timeString;
            '', '' ;
            'Sample Info',         app.SampleInfo;
            'Comments',            app.Comments;
            'Additional Comments', app.additionalComments;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V',        app.start_voltage;
            'Stop Voltage, V',         app.stop_voltage;
            'Sweep Mode Scan',         app.sweep_mode_scan;
            'Sweep Points',            app.sweep_samplecount;
            'Loop Count',              app.loopcount;
            'X Mapping',               app.xmapping;
            'Settling Inaccuracy',     app.sweep_inaccuracy;
            'Averaging Time Constant', app.averaging_tc;
            'Averaging Sample',        app.averaging_sample;
            'Bandwidth Control',       app.bandwidthcontrol;
            'Bandwidth Overlap',       app.bandwidthoverlap;
            '', '' ;
            'Device Settings', '' ;
            'IMPS Parameters', '' ;
            'IMPS Channel', app.imps_channel;
            'IMPS Frequency, Hz', app.imps_freq;
            'IMPS Amplitude, V', app.imps_amplitude;
            'IMPS Mode', app.imps_mode;
            'IMPS Model', app.imps_model;
            '', '' ;
            'Signal Input', '' ;
            'Input Channel', app.in_c;
            'Input 50 Ohm', app.input_imp50;
            'Input AC', app.input_ac;
            'Input Range', app.input_range;
            '', '' ;
            'Signal Output', '' ;
            'Output Channel', app.out_c;
            'Output On', app.sigout_on;
            'Output Range', app.sigout_range;
            'Output Amplitude', app.sigout_amplitude;
            'Output Mixer Enable', app.sigout_mixer_enable;
            '', '' ;
            'Demodulator', '' ;
            'Demod Channel', app.demod_c;
            'Demod Phase Shift', app.demod_phaseshift;
            'Demod Order', app.demod_order;
            'Demod Rate', app.demod_rate;
            'Demod Harmonic', app.demod_harmonic;
            'Demod Enable', app.demod_enable;
            'Demod Time Constant', app.demod_tc;
            '', '' ;
            'Oscillator', '' ;
            'Oscillator Channel', app.osc_c;
            'Oscillator Frequency, Hz', app.osc_freq;
            };
            
            %% Device Settings
            
            % MFIA impedance excitation is fixed to Signal Output 1, so read the actual channel from the device.
            app.out_c = ziDAQ('getInt', sprintf('/%s/imps/%d/output/select', app.device_id, app.imps_channel));
            app.out_mixer_c = str2double(ziGetDefaultSigoutMixerChannel(app.props, app.out_c));
            
            % -----------------------------
            % Signal input
            % -----------------------------
            ziDAQ('setInt',    sprintf('/%s/sigins/%d/imp50', app.device_id, app.in_c),  app.input_imp50);
            ziDAQ('setInt',    sprintf('/%s/sigins/%d/ac',    app.device_id, app.in_c),  app.input_ac);
            ziDAQ('setDouble', sprintf('/%s/sigins/%d/range', app.device_id, app.in_c),  app.input_range);
            
            % -----------------------------
            % Global signal-output state
            % -----------------------------
            % Turn outputs off while changing output-related settings.
            ziDAQ('setInt',    sprintf('/%s/imps/%d/output/on', app.device_id, app.imps_channel), 0);
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/on',     app.device_id, app.out_c),        0);
            
            % Make Signal Output 1 explicit for CV bias sweeps.
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/autorange', app.device_id, app.out_c), 0);
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/imp50',     app.device_id, app.out_c), 0);  % Hi-Z load assumption
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/diff',      app.device_id, app.out_c), 0);  % single-ended
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/add',       app.device_id, app.out_c), 0);  % no Aux In summation
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/offset',    app.device_id, app.out_c), 0);
            
            % Hardcode the physical signal-output range to 10 V.
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/range', app.device_id, app.out_c), 10);
            
            % Keep your existing signal-output mixer settings explicit.
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/amplitudes/*',  app.device_id, app.out_c), 0);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/amplitudes/%d', app.device_id, app.out_c, app.out_mixer_c), app.sigout_amplitude);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/enables/%d',    app.device_id, app.out_c, app.out_mixer_c), app.sigout_mixer_enable);
            
            % -----------------------------
            % Demodulator / oscillator
            % -----------------------------
            ziDAQ('setDouble', sprintf('/%s/demods/*/phaseshift',   app.device_id), app.demod_phaseshift);
            ziDAQ('setInt',    sprintf('/%s/demods/*/order',        app.device_id), app.demod_order);
            ziDAQ('setDouble', sprintf('/%s/demods/%d/rate',        app.device_id, app.demod_c), app.demod_rate);
            ziDAQ('setInt',    sprintf('/%s/demods/%d/harmonic',    app.device_id, app.demod_c), app.demod_harmonic);
            ziDAQ('setInt',    sprintf('/%s/demods/%d/enable',      app.device_id, app.demod_c), app.demod_enable);
            ziDAQ('setDouble', sprintf('/%s/demods/*/timeconstant', app.device_id), app.demod_tc);
            ziDAQ('setInt',    sprintf('/%s/demods/*/adcselect',    app.device_id), app.in_c);
            ziDAQ('setInt',    sprintf('/%s/demods/*/oscselect',    app.device_id), app.osc_c);
            ziDAQ('setDouble', sprintf('/%s/oscs/%d/freq',          app.device_id, app.osc_c), app.osc_freq);
            
            % -----------------------------
            % Impedance-analyzer settings
            % -----------------------------
            ziDAQ('setInt',    sprintf('/%s/imps/%d/enable', app.device_id, app.imps_channel), 1);
            
            % Use manual test-signal amplitude, automatic input range, automatic bandwidth.
            ziDAQ('setInt',    sprintf('/%s/imps/%d/auto/output',     app.device_id, app.imps_channel), 0);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/auto/inputrange', app.device_id, app.imps_channel), 1);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/auto/bw',         app.device_id, app.imps_channel), 1);
            
            % Accuracy / reliability defaults.
            ziDAQ('setInt',    sprintf('/%s/imps/%d/calib/internal/enable', app.device_id, app.imps_channel), 1);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/calib/user/enable',     app.device_id, app.imps_channel), 0);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/oneperiod/enable',      app.device_id, app.imps_channel), 0);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/maxbandwidth',          app.device_id, app.imps_channel), 1e3);
            
            % Make current/voltage measurement paths explicit.
            ziDAQ('setInt',    sprintf('/%s/imps/%d/ac',                  app.device_id, app.imps_channel), app.input_ac);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/current/inputselect', app.device_id, app.imps_channel), 0); % Current Input 1
            ziDAQ('setInt',    sprintf('/%s/imps/%d/current/invert',      app.device_id, app.imps_channel), 0);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/current/pid/enable',  app.device_id, app.imps_channel), 0);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/current/scaling',     app.device_id, app.imps_channel), 1);
            
            ziDAQ('setInt',    sprintf('/%s/imps/%d/voltage/inputselect', app.device_id, app.imps_channel), 0); % Voltage Input 1
            ziDAQ('setInt',    sprintf('/%s/imps/%d/voltage/invert',      app.device_id, app.imps_channel), 0);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/voltage/scaling',     app.device_id, app.imps_channel), 1);
            
            % Make the impedance demodulator settings explicit.
            ziDAQ('setInt',    sprintf('/%s/imps/%d/demod/harmonic',    app.device_id, app.imps_channel), app.demod_harmonic);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/demod/order',       app.device_id, app.imps_channel), app.demod_order);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/demod/oscselect',   app.device_id, app.imps_channel), app.osc_c);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/demod/rate',        app.device_id, app.imps_channel), app.demod_rate);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/demod/sinc',        app.device_id, app.imps_channel), 0);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/demod/timeconstant',app.device_id, app.imps_channel), app.demod_tc);
            
            % Main impedance settings.
            ziDAQ('setDouble', sprintf('/%s/imps/%d/freq',             app.device_id, app.imps_channel), app.imps_freq);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/output/range',     app.device_id, app.imps_channel), 10);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/output/amplitude', app.device_id, app.imps_channel), app.imps_amplitude);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/mode',             app.device_id, app.imps_channel), app.imps_mode);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/model',            app.device_id, app.imps_channel), app.imps_model);
            
            % Turn outputs back on after configuration.
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/on',     app.device_id, app.out_c),        app.sigout_on);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/output/on', app.device_id, app.imps_channel), 1);
            
            ziDAQ('sync');
            pause(1);
       
            %% Live UI preparation
            uiUpdateEvery = 5;
            lastUiPointCount = 0;
            
            if app.sweep_samplecount > 1
                stepValue = (app.stop_voltage - app.start_voltage) / (app.sweep_samplecount - 1);
            else
                stepValue = 0;
            end
            
            app.ConstantValuesTabel.ColumnName = {'Frequency [Hz]'; 'Amplitude [V]'};
            app.ConstantValuesTabel.Data = [app.imps_freq, app.imps_amplitude];
            
            % Repurpose the live readout labels
            app.VoltageLabel_2.Text = 'Voltage';
            app.VoltageLabel.Text = 'Capacitance';
            app.VoltsLabel.Text = '[V]';
            app.AmpsLabel.Text = '[F]';
            
            app.VoltageTextArea.Value = {''};
            app.CurrentTextArea.Value = {''};
            app.StepTextArea.Value = {sprintf('%.6g', stepValue)};
            app.SweepPointsTextArea.Value = {sprintf('%d', app.sweep_samplecount)};
            app.CalculatedPointsTextArea.Value = {'0'};
            app.DelaySTextArea.Value = {'0'};
            app.MeasureWindowSTextArea.Value = {sprintf('%.6g', app.averaging_tc * app.demod_tc)};
            app.MeasureCountTextArea.Value = {sprintf('%d', app.averaging_sample)};
            
            %% Measurements
            app.StateoftheExperimentTextArea.Value = {'Initiated Measurements'};
            app.IndicatorStatusLamp.Color = 'b';
            
            exp = ziDAQ('sweep');
            samplePath = sprintf('/%s/imps/%d/sample', app.device_id, app.imps_channel);
            
            ziDAQ('set', exp, 'device', app.device_id);
            ziDAQ('set', exp, 'gridnode', sprintf('sigouts/%d/offset', app.out_c));
            ziDAQ('set', exp, 'start', app.start_voltage);
            ziDAQ('set', exp, 'stop', app.stop_voltage);
            ziDAQ('set', exp, 'sweep/scan', app.sweep_mode_scan);
            ziDAQ('set', exp, 'samplecount', app.sweep_samplecount);
            ziDAQ('set', exp, 'settling/inaccuracy', app.sweep_inaccuracy);
            ziDAQ('set', exp, 'loopcount', app.loopcount);
            ziDAQ('set', exp, 'xmapping', app.xmapping);
            ziDAQ('set', exp, 'settling/time', 0);
            ziDAQ('set', exp, 'averaging/tc', app.averaging_tc);
            ziDAQ('set', exp, 'averaging/sample', app.averaging_sample);
            ziDAQ('set', exp, 'bandwidthcontrol', app.bandwidthcontrol);
            ziDAQ('set', exp, 'bandwidthoverlap', app.bandwidthoverlap);
            ziDAQ('subscribe', exp, samplePath);
            ziDAQ('execute', exp);
            
            while ~ziDAQ('finished', exp)
                if app.stopExperiment
                    break;
                end
            
                pause(0.05);
                data_read_exp = ziDAQ('read', exp);
            
                if ziCheckPathInData(data_read_exp, samplePath)
                    result_struct = data_read_exp.(app.device_id).imps(app.imps_channel + 1).sample{1};
            
                    if ~isempty(result_struct)
                        app.voltage_source = result_struct.grid(:);
                        app.capacitance_measure = result_struct.param1(:);
                        valid = ~isnan(app.voltage_source) & ~isnan(app.capacitance_measure);
                        validCount = nnz(valid);
            
                        if validCount > 0 && (validCount == 1 || validCount - lastUiPointCount >= uiUpdateEvery)
                            lastIdx = find(valid, 1, 'last');
            
                            plot(app.CV_Plot_Linear, app.voltage_source(valid), app.capacitance_measure(valid), '.-', 'LineWidth', 1.5);
                            hold(app.CV_Plot_Linear, 'on');
                            plot(app.CV_Plot_Linear, app.voltage_source(lastIdx), ...
                                app.capacitance_measure(lastIdx), 'ko', 'MarkerSize', 8, 'LineWidth', 1.5);
                            hold(app.CV_Plot_Linear, 'off');
            
                            semilogy(app.CV_Plot_Logarithmic, app.voltage_source(valid), abs(app.capacitance_measure(valid)), '.-', 'LineWidth', 1.5);
                            hold(app.CV_Plot_Logarithmic, 'on');
                            plot(app.CV_Plot_Logarithmic, app.voltage_source(lastIdx), ...
                                abs(app.capacitance_measure(lastIdx)), 'ko', 'MarkerSize', 8, 'LineWidth', 1.5);
                            hold(app.CV_Plot_Logarithmic, 'off');
            
                            app.VoltageTextArea.Value = {sprintf('%.6g', app.voltage_source(lastIdx))};
                            app.CurrentTextArea.Value = {sprintf('%.6e', app.capacitance_measure(lastIdx))};
                            app.CalculatedPointsTextArea.Value = {sprintf('%d', validCount)};
                            app.ConstantValuesTabel.Data = [app.imps_freq, app.imps_amplitude];
                            app.StateoftheExperimentTextArea.Value = {sprintf('Sweep progress %0.0f%%', ziDAQ('progress', exp) * 100)};
            
                            drawnow limitrate;
                            lastUiPointCount = validCount;
                        end
                    end
                end
            end
            
            %% Final live refresh
            data_read_exp = ziDAQ('read', exp);
            
            if ziCheckPathInData(data_read_exp, samplePath)
                result_struct = data_read_exp.(app.device_id).imps(app.imps_channel + 1).sample{1};
            
                if ~isempty(result_struct)
                    app.voltage_source = result_struct.grid(:);
                    app.capacitance_measure = result_struct.param1(:);
                end
            end

            valid = ~isnan(app.voltage_source) & ~isnan(app.capacitance_measure);
            validCount = nnz(valid);
            
            if validCount > 0
                lastIdx = find(valid, 1, 'last');
            
                plot(app.CV_Plot_Linear, app.voltage_source(valid), app.capacitance_measure(valid), '.-', 'LineWidth', 1.5);
                hold(app.CV_Plot_Linear, 'on');
                plot(app.CV_Plot_Linear, app.voltage_source(lastIdx), ...
                    app.capacitance_measure(lastIdx), 'ko', 'MarkerSize', 8, 'LineWidth', 1.5);
                hold(app.CV_Plot_Linear, 'off');
            
                semilogy(app.CV_Plot_Logarithmic, app.voltage_source(valid), abs(app.capacitance_measure(valid)), '.-', 'LineWidth', 1.5);
                hold(app.CV_Plot_Logarithmic, 'on');
                plot(app.CV_Plot_Logarithmic, app.voltage_source(lastIdx), ...
                    abs(app.capacitance_measure(lastIdx)), 'ko', 'MarkerSize', 8, 'LineWidth', 1.5);
                hold(app.CV_Plot_Logarithmic, 'off');
            
                app.VoltageTextArea.Value = {sprintf('%.6g', app.voltage_source(lastIdx))};
                app.CurrentTextArea.Value = {sprintf('%.6e', app.capacitance_measure(lastIdx))};
                app.CalculatedPointsTextArea.Value = {sprintf('%d', validCount)};
                drawnow limitrate;
            end
            
            %% Finishing the Experiment
            app.IndicatorStatusLamp.Color = 'c';
            app.StateoftheExperimentTextArea.Value = {'Saving Data'};
            
            MeasurementtableData = [(1:nnz(valid))', app.voltage_source(valid), app.capacitance_measure(valid)];
            app.MeasurementTable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.MeasurementTable.Data = MeasurementtableData;
            
            [filename, pathname] = uiputfile('.xlsx', 'Save as', app.defaultFolder);
            if isequal(filename, 0) || isequal(pathname, 0)
                app.StateoftheExperimentTextArea.Value = {'The Experiment is done, the Data is not saved!'};
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value = {'The Experiment is done, the Data is saved!'};
                app.measurement_var_names = {'Voltage', 'Capacitance'};
                app.measurement_table = table(app.voltage_source(valid), app.capacitance_measure(valid), ...
                    'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                figure(app.UIFigure)
            end
            
            ziDAQ('unsubscribe', exp, samplePath);
            ziDAQ('clear', exp);
            
            app.StateoftheExperimentTextArea.Value = {'Experiment Finished'};
            app.IndicatorStatusLamp.Color = 'w';
            app.MFIAZIConnectionStatusLamp.Color = 'w';
            app.StartExperimentButton.Enable = "on";

        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.stopExperiment = true;
            app.IndicatorStatusLamp.Color = 'k';
            app.MFIAZIConnectionStatusLamp.Color = 'w';
            app.StateoftheExperimentTextArea.Value = {'Measurements Stopped'};
            app.StartExperimentButton.Enable = "on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end

        % Button pushed function: SaveDataAdditionalCommentButton
        function SaveDataAdditionalCommentButtonPushed(app, event)
            if isempty(app.voltage_source) || isempty(app.capacitance_measure) || isempty(app.settings)
                uialert(app.UIFigure, 'No measurement data is available to save.', 'Save Error');
                return;
            end
            
            prompt = {'Enter additional comments:'};
            dlgtitle = 'Additional Comments';
            dims = [1 50];
            definput = {'No additional comments'};
            app.additionalCommentsCell = inputdlg(prompt, dlgtitle, dims, definput);
            
            if isempty(app.additionalCommentsCell)
                app.additionalComments = 'No Comments Provided';
            else
                app.additionalComments = app.additionalCommentsCell{1};
            end
            
            % Row 7, column 2 stores "Additional Comments" in the settings cell.
            app.settings{7, 2} = app.additionalComments;
            
            valid = ~isnan(app.voltage_source) & ~isnan(app.capacitance_measure);
            
            [filename, pathname] = uiputfile('*.xlsx', 'Save as', app.defaultFolder);
            if isequal(filename, 0) || isequal(pathname, 0)
                app.StateoftheExperimentTextArea.Value = {'The experiment is done, the data is not saved!'};
                figure(app.UIFigure)
            else
                savePath = fullfile(pathname, filename);
                app.StateoftheExperimentTextArea.Value = {'The experiment is done, the data is saved!'};
            
                app.measurement_var_names = {'Voltage', 'Capacitance'};
                app.measurement_table = table(app.voltage_source(valid), app.capacitance_measure(valid), ...
                    'VariableNames', app.measurement_var_names);
            
                writecell(app.settings, savePath, 'Sheet', app.sheet1);
                writetable(app.measurement_table, savePath, 'Sheet', app.sheet2);
                figure(app.UIFigure)
            end

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            prevFolder = app.DefaultFolderEditField.Value;
            selected = uigetdir(prevFolder, 'Select Folder');
            
            if isequal(selected, 0)
                figure(app.UIFigure)
                return;
            end
            
            setpref('MyApp', 'DataFolder', selected);
            app.DefaultFolderEditField.Value = selected;
            figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CapacitanceVoltageCVMFIAZurichTab = uitab(app.TabGroup2);
            app.CapacitanceVoltageCVMFIAZurichTab.Title = 'Capacitance-Voltage (C-V, MFIA Zurich)';
            app.TabGroup = uitabgroup(app.CapacitanceVoltageCVMFIAZurichTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CVZurichInstrumentsMFIALabel = uilabel(app.SettingsTab);
            app.CVZurichInstrumentsMFIALabel.Text = 'C-V, Zurich Instruments MFIA';
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.DefaultFolderPanel = uipanel(app.SettingsTab);
            app.DefaultFolderPanel.Title = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.DefaultFolderPanel, 'text');
            app.SelectFolderButton = uibutton(app.DefaultFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.DeviceSettingsPanel = uipanel(app.SettingsTab);
            app.DeviceSettingsPanel.Title = 'Device Settings';
            app.in_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.in_cEditFieldLabel.Text = 'in_c';
            app.in_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_imp50EditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_imp50EditFieldLabel.Text = 'input_imp50';
            app.input_imp50EditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_imp50EditField.Value = 1;
            app.out_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.out_cEditFieldLabel.Text = 'out_c';
            app.out_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_acEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_acEditFieldLabel.Text = 'input_ac';
            app.input_acEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_acEditField.Value = 1;
            app.input_rangeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_rangeEditFieldLabel.Text = 'input_range';
            app.input_rangeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_rangeEditField.Value = 1;
            app.sigout_onEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_onEditFieldLabel.Text = 'sigout_on';
            app.sigout_onEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_onEditField.Value = 1;
            app.sigout_rangeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_rangeEditFieldLabel.Text = 'sigout_range';
            app.sigout_rangeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_rangeEditField.Value = 1;
            app.sigout_amplitudeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_amplitudeEditFieldLabel.Text = 'sigout_amplitude';
            app.sigout_amplitudeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_amplitudeEditField.Value = 0.05;
            app.sigout_mixer_enableEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_mixer_enableEditFieldLabel.Text = 'sigout_mixer_enable';
            app.sigout_mixer_enableEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_mixer_enableEditField.Value = 1;
            app.demod_phaseshiftEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_phaseshiftEditFieldLabel.Text = 'demod_phaseshift';
            app.demod_phaseshiftEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_orderEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_orderEditFieldLabel.Text = 'demod_order';
            app.demod_orderEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_orderEditField.Value = 4;
            app.demod_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_cEditFieldLabel.Text = 'demod_c';
            app.demod_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_rateEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_rateEditFieldLabel.Text = 'demod_rate';
            app.demod_rateEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_rateEditField.Value = 13000;
            app.demod_harmonicEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_harmonicEditFieldLabel.Text = 'demod_harmonic';
            app.demod_harmonicEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_harmonicEditField.Value = 1;
            app.demod_enableEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_enableEditFieldLabel.Text = 'demod_enable';
            app.demod_enableEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_enableEditField.Value = 1;
            app.demod_tcEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_tcEditFieldLabel.Text = 'demod_tc';
            app.demod_tcEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_tcEditField.Value = 0.007;
            app.osc_freqEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.osc_freqEditFieldLabel.Text = 'osc_freq';
            app.osc_freqEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.osc_freqEditField.Value = 3000000;
            app.osc_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.osc_cEditFieldLabel.Text = 'osc_c';
            app.osc_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_channelEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_channelEditFieldLabel.Text = 'imps_channel';
            app.imps_channelEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_amplitudeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_amplitudeEditFieldLabel.Text = 'imps_amplitude';
            app.imps_amplitudeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_amplitudeEditField.Value = 0.01;
            app.imps_freqEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_freqEditFieldLabel.Text = 'imps_freq';
            app.imps_freqEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_freqEditField.Value = 100000;
            app.imps_modeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_modeEditFieldLabel.Text = 'imps_mode';
            app.imps_modeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_modeEditField.Value = 1;
            app.imps_modelEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_modelEditFieldLabel.Text = 'imps_model';
            app.imps_modelEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.start_voltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.start_voltageEditFieldLabel.Text = 'start_voltage';
            app.start_voltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.start_voltageEditField.Value = -0.5;
            app.stop_voltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.stop_voltageEditFieldLabel.Text = 'stop_voltage';
            app.stop_voltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.stop_voltageEditField.Value = 0.5;
            app.sweep_mode_scanEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.sweep_mode_scanEditFieldLabel.Text = 'sweep_mode_scan';
            app.sweep_mode_scanEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.sweep_mode_scanEditField.Value = 2;
            app.sweep_samplecountEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.sweep_samplecountEditFieldLabel.Text = 'sweep_samplecount';
            app.sweep_samplecountEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.sweep_samplecountEditField.Value = 100;
            app.loop_countEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.loop_countEditFieldLabel.Text = 'loop_count';
            app.loop_countEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.loop_countEditField.Value = 1;
            app.xmappingEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.xmappingEditFieldLabel.Text = 'xmapping';
            app.xmappingEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.sweep_inaccuracyEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.sweep_inaccuracyEditFieldLabel.Text = 'sweep_inaccuracy';
            app.sweep_inaccuracyEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.sweep_inaccuracyEditField.Value = 0.001;
            app.averaging_tcEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.averaging_tcEditFieldLabel.Text = 'averaging_tc';
            app.averaging_tcEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.averaging_tcEditField.Value = 50;
            app.averaging_sampleEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.averaging_sampleEditFieldLabel.Text = 'averaging_sample';
            app.averaging_sampleEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.bandwidthcontrolEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.bandwidthcontrolEditFieldLabel.Text = 'bandwidthcontrol';
            app.bandwidthcontrolEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.bandwidthcontrolEditField.Value = 2;
            app.bandwidthoverlapEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.bandwidthoverlapEditFieldLabel.Text = 'bandwidthoverlap';
            app.bandwidthoverlapEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Voltage [V]'; 'Capacitance [F]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'Amplitude'};
            app.ConstantValuesTabel.RowName = {};
            app.MFIAPropertiesPanel = uipanel(app.Tables);
            app.MFIAPropertiesPanel.Title = 'MFIA Properties';
            app.Discovery_ID_Textarea = uitextarea(app.MFIAPropertiesPanel);
            app.Discovery_ID_Textarea.Editable = 'off';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.CV_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Logarithmic, 'Logarithmic C-V, MFIA')
            xlabel(app.CV_Plot_Logarithmic, 'Voltage [V]')
            ylabel(app.CV_Plot_Logarithmic, 'Logarithmic Capacitance [F]')
            zlabel(app.CV_Plot_Logarithmic, 'Z')
            app.CV_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Linear, 'Linear C-V, MFIA')
            xlabel(app.CV_Plot_Linear, 'Voltage [V]')
            ylabel(app.CV_Plot_Linear, 'Linear Capacitance [F]')
            zlabel(app.CV_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.VoltageTextArea = uitextarea(app.MeasurementsPanel);
            app.VoltageTextArea.Editable = 'off';
            app.VoltsLabel = uilabel(app.MeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.CalculatedPointsLabel_2 = uilabel(app.ConstantsPanel);
            app.CalculatedPointsLabel_2.Text = 'Step';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.CalculatedPointsTextArea_2Label = uilabel(app.ConstantsPanel);
            app.CalculatedPointsTextArea_2Label.Text = 'Calculated Points';
            app.StepTextArea = uitextarea(app.ConstantsPanel);
            app.StepTextArea.Editable = 'off';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.CalculatedPointsTextArea = uitextarea(app.ConstantsPanel);
            app.CalculatedPointsTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorStatusLampLabel = uilabel(app.CapacitanceVoltageCVMFIAZurichTab);
            app.IndicatorStatusLampLabel.Text = {'Indicator'; 'Status'};
            app.IndicatorStatusLamp = uilamp(app.CapacitanceVoltageCVMFIAZurichTab);
            app.StopExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataAdditionalCommentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.SaveDataAdditionalCommentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataAdditionalCommentButtonPushed, true);
            app.SaveDataAdditionalCommentButton.Text = {'Save Data +'; 'Additional Comment'};
            app.MFIAZIConnectionStatusLampLabel = uilabel(app.CapacitanceVoltageCVMFIAZurichTab);
            app.MFIAZIConnectionStatusLampLabel.Text = {'MFIA  ZI'; 'Connection Status'};
            app.MFIAZIConnectionStatusLamp = uilamp(app.CapacitanceVoltageCVMFIAZurichTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_Zurich_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end