classdef IV_Keithley_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentVoltageIVKeithleyTab     matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        SelectFolderButton              matlab.ui.control.Button
        DefaultFolderEditField          matlab.ui.control.EditField
        DefaultFolderEditFieldLabel     matlab.ui.control.Label
        ConnectionStatusPanel           matlab.ui.container.Panel
        KEITHLEYSourceMeter2450Lamp     matlab.ui.control.Lamp
        KEITHLEYSourceMeter2450LampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        CommonSettingsPanel             matlab.ui.container.Panel
        UpdateIntervalEditField         matlab.ui.control.NumericEditField
        UpdateIntervalEditFieldLabel    matlab.ui.control.Label
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        SourcetoMeasureDelaySEditField  matlab.ui.control.NumericEditField
        SourcetoMeasureDelaySEditFieldLabel  matlab.ui.control.Label
        RepeatEditField                 matlab.ui.control.NumericEditField
        RepeatEditFieldLabel            matlab.ui.control.Label
        SourceSweepPointsEditField      matlab.ui.control.NumericEditField
        SourceSweepPointsLabel          matlab.ui.control.Label
        MeasurementParametersPanel      matlab.ui.container.Panel
        SenseListBox                    matlab.ui.control.ListBox
        InputTerminalsListBox           matlab.ui.control.ListBox
        OutputOffListBox                matlab.ui.control.ListBox
        SenseListBoxLabel               matlab.ui.control.Label
        InputTerminalsListBoxLabel      matlab.ui.control.Label
        OutputOffListBoxLabel           matlab.ui.control.Label
        MinimumRangeListBox             matlab.ui.control.ListBox
        CurrentRangeListBox             matlab.ui.control.ListBox
        VoltageRangeListBox             matlab.ui.control.ListBox
        MinimumRangeListBoxLabel        matlab.ui.control.Label
        CurrentRangeListBoxLabel        matlab.ui.control.Label
        VoltageRangeListBoxLabel        matlab.ui.control.Label
        AutoZeroSwitch                  matlab.ui.control.Switch
        AutoZeroSwitchLabel             matlab.ui.control.Label
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentLimitEditField           matlab.ui.control.NumericEditField
        CurrentLimitEditFieldLabel      matlab.ui.control.Label
        DualSweepSwitch                 matlab.ui.control.Switch
        DualSweepSwitchLabel            matlab.ui.control.Label
        VoltsLabel_7                    matlab.ui.control.Label
        StopVoltageEditField            matlab.ui.control.NumericEditField
        StopVoltageEditFieldLabel       matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        StartVoltageEditField           matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel      matlab.ui.control.Label
        IVKeithleyModeSweepLabel        matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        DelaySTextArea                  matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        MeasurementWindowLabel          matlab.ui.control.Label
        DelaySTextAreaLabel             matlab.ui.control.Label
        CalculatedPointsTextArea        matlab.ui.control.TextArea
        SweepPointsTextArea             matlab.ui.control.TextArea
        StepTextArea                    matlab.ui.control.TextArea
        CalculatedPointsTextArea_2Label  matlab.ui.control.Label
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        CalculatedPointsLabel_2         matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        IV_Plot_Linear                  matlab.ui.control.UIAxes
        IV_Plot_Logarithmic             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        data;
        sheet1;
        sheet2;
        settings;
        measurement_table;
        measurement_var_names;
        consttableData;
        defaultFolder;
            
        i;
        voltage_source;
        current_measure;

        KLYSM2450;
        connectionStatusKeithley;
        freq;
        Calculatedpoints;
        aftervoltage;
        Step;
   
        StartVoltage;
        StopVoltage;
        Voltagerange;
        Currentlimit;
        Dualsweep;
        Currentrange;
        Minimumrange
        Autozero;
        Inputterminals;
        Outputoff;
        Sense;

        Sweeppoints;
        CurrentRange;
        Repeat;
        SourcetoMeasureDelay;
        measurement_window;
        NPLC;
        Measurecount;
        updateInterval;
        stopExperiment;

    end

    methods (Access = public)
        function connectDeviceKeithley(app)
            if strcmp(app.connectionStatusKeithley,'Connected')
                delete(app.KLYSM2450)
                app.connectionStatusKeithley='Not Connected';
            end
            try
                app.KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
                app.KEITHLEYSourceMeter2450Lamp.Color='g';
                app.connectionStatusKeithley='Connected';
            catch
                app.KEITHLEYSourceMeter2450Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The Connection to the device has failed!');
                app.connectionStatusKeithley='Not Connected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
            defaultFallback = fullfile(userpath,'MyAppData');
            app.DefaultFolderEditField.Value = getpref('MyApp','DataFolder', defaultFallback);
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            
            ivKeithley4StartExperiment(app);
            
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.KEITHLEYSourceMeter2450Lamp.Color='w';

            app.StateoftheExperimentTextArea.Value=('The Experiment has been stopped, Save the data!');
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end
            app.stopExperiment = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V', app.StartVoltage ;
            'Stop Voltage, V', app.StopVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Step, V', app.Step ;
            '', '' ;
            'Dual Sweep', app.Dualsweep ;
            'Repeat', app.Repeat ;
            'Sweep Points', app.Sweeppoints ;
            'Calculated Points', app.Calculatedpoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Voltage', 'Current'};
                app.measurement_table = table(app.voltage_source, app.current_measure ,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
        prevFolder = app.DefaultFolderEditField.Value;
        selected = uigetdir(prevFolder, 'Select Folder');
        if selected ~= 0
            setpref('MyApp','DataFolder', selected);
            app.DefaultFolderEditField.Value = selected;
        end
        figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CurrentVoltageIVKeithleyTab = uitab(app.TabGroup2);
            app.CurrentVoltageIVKeithleyTab.Title = 'Current-Voltage (I-V, Keithley)';
            app.TabGroup = uitabgroup(app.CurrentVoltageIVKeithleyTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.IVKeithleyModeSweepLabel = uilabel(app.SettingsTab);
            app.IVKeithleyModeSweepLabel.Text = 'I-V / Keithley, Mode: Sweep';
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.StartVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StartVoltageEditField.Value = -1;
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_6.Text = '[Volts]';
            app.StopVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopVoltageEditFieldLabel.Text = 'Stop Voltage';
            app.StopVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopVoltageEditField.Value = 1;
            app.VoltsLabel_7 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_7.Text = '[Volts]';
            app.DualSweepSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.DualSweepSwitchLabel.Text = 'Dual Sweep';
            app.DualSweepSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.CurrentLimitEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentLimitEditFieldLabel.Text = 'Current Limit';
            app.CurrentLimitEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.CurrentLimitEditField.Value = 0.1;
            app.AmpsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.AmpsLabel_2.Text = '[Amps]';
            app.AutoZeroSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.AutoZeroSwitchLabel.Text = 'Auto Zero';
            app.AutoZeroSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.VoltageRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.VoltageRangeListBoxLabel.Text = 'Voltage Range';
            app.CurrentRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentRangeListBoxLabel.Text = 'Current Range';
            app.MinimumRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.MinimumRangeListBoxLabel.Text = 'Minimum Range';
            app.VoltageRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.VoltageRangeListBox.Items = {'Auto'};
            app.VoltageRangeListBox.Value = 'Auto';
            app.CurrentRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.CurrentRangeListBox.Items = {'Auto'};
            app.CurrentRangeListBox.Value = 'Auto';
            app.MinimumRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.MinimumRangeListBox.Items = {'10 nA'};
            app.MinimumRangeListBox.Value = '10 nA';
            app.OutputOffListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.OutputOffListBoxLabel.Text = 'Output Off';
            app.InputTerminalsListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.InputTerminalsListBoxLabel.Text = 'Input Terminals';
            app.SenseListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.SenseListBoxLabel.Text = 'Sense';
            app.OutputOffListBox = uilistbox(app.MeasurementParametersPanel);
            app.OutputOffListBox.Items = {'Normal'};
            app.OutputOffListBox.Value = 'Normal';
            app.InputTerminalsListBox = uilistbox(app.MeasurementParametersPanel);
            app.InputTerminalsListBox.Items = {'Rear', 'Front'};
            app.InputTerminalsListBox.Value = 'Rear';
            app.SenseListBox = uilistbox(app.MeasurementParametersPanel);
            app.SenseListBox.Items = {'2-Wire', '4-Wire'};
            app.SenseListBox.Value = '2-Wire';
            app.CommonSettingsPanel = uipanel(app.SettingsTab);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.SourceSweepPointsLabel = uilabel(app.CommonSettingsPanel);
            app.SourceSweepPointsLabel.Text = 'Source/ Sweep Points';
            app.SourceSweepPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourceSweepPointsEditField.Limits = [2 Inf];
            app.SourceSweepPointsEditField.Value = 21;
            app.RepeatEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.SourcetoMeasureDelaySEditFieldLabel.Text = {'Source to Measure Delay'; '[S]'};
            app.SourcetoMeasureDelaySEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditField.Value = 0.1;
            app.NPLCEditField_2Label = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.NPLCEditField.Value = 1;
            app.NPLCEditField_2Label_2 = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.CommonSettingsPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.CommonSettingsPanel, 'slider');
            app.UpdateIntervalEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.UpdateIntervalEditFieldLabel.Text = 'Update Interval';
            app.UpdateIntervalEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.UpdateIntervalEditField.Value = 1;
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.KEITHLEYSourceMeter2450LampLabel = uilabel(app.ConnectionStatusPanel);
            app.KEITHLEYSourceMeter2450LampLabel.Text = 'KEITHLEY SourceMeter 2450';
            app.KEITHLEYSourceMeter2450Lamp = uilamp(app.ConnectionStatusPanel);
            app.DefaultFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultFolderEditFieldLabel.Text = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.SettingsTab, 'text');
            app.SelectFolderButton = uibutton(app.SettingsTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Voltage [V]'; 'Current [A]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'NPLC'};
            app.ConstantValuesTabel.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.IV_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.IV_Plot_Logarithmic, 'Logarithmic I-V Characteristics')
            xlabel(app.IV_Plot_Logarithmic, 'Voltage [V]')
            ylabel(app.IV_Plot_Logarithmic, 'Logarithmic Current [A]')
            zlabel(app.IV_Plot_Logarithmic, 'Z')
            app.IV_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.IV_Plot_Linear, 'Linear I-V Characteristics')
            xlabel(app.IV_Plot_Linear, 'Voltage [V]')
            ylabel(app.IV_Plot_Linear, 'Linear Current [A]')
            zlabel(app.IV_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.VoltageTextArea = uitextarea(app.MeasurementsPanel);
            app.VoltageTextArea.Editable = 'off';
            app.VoltsLabel = uilabel(app.MeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.CalculatedPointsLabel_2 = uilabel(app.ConstantsPanel);
            app.CalculatedPointsLabel_2.Text = 'Step';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.CalculatedPointsTextArea_2Label = uilabel(app.ConstantsPanel);
            app.CalculatedPointsTextArea_2Label.Text = 'Calculated Points';
            app.StepTextArea = uitextarea(app.ConstantsPanel);
            app.StepTextArea.Editable = 'off';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.CalculatedPointsTextArea = uitextarea(app.ConstantsPanel);
            app.CalculatedPointsTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CurrentVoltageIVKeithleyTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CurrentVoltageIVKeithleyTab);
            app.StopExperimentButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IV_Keithley_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end