classdef It_Keithley_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentVoltageItKeithleyTab     matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        DefaultFolderEditField          matlab.ui.control.EditField
        DefaultFolderEditFieldLabel     matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        ConnectionStatusPanel           matlab.ui.container.Panel
        KEITHLEYSourceMeter2450Lamp     matlab.ui.control.Lamp
        KEITHLEYSourceMeter2450LampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        CommonSettingsPanel             matlab.ui.container.Panel
        UpdateIntervalEditField         matlab.ui.control.NumericEditField
        UpdateIntervalEditFieldLabel    matlab.ui.control.Label
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        SourcetoMeasureDelaySEditField  matlab.ui.control.NumericEditField
        SourcetoMeasureDelaySEditFieldLabel  matlab.ui.control.Label
        SourceSweepPointsEditField      matlab.ui.control.NumericEditField
        SourceSweepPointsLabel          matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        MeasurementParametersPanel      matlab.ui.container.Panel
        SenseListBox                    matlab.ui.control.ListBox
        InputTerminalsListBox           matlab.ui.control.ListBox
        OutputOffListBox                matlab.ui.control.ListBox
        SenseListBoxLabel               matlab.ui.control.Label
        InputTerminalsListBoxLabel      matlab.ui.control.Label
        OutputOffListBoxLabel           matlab.ui.control.Label
        MinimumRangeListBox             matlab.ui.control.ListBox
        CurrentRangeListBox             matlab.ui.control.ListBox
        VoltageRangeListBox             matlab.ui.control.ListBox
        MinimumRangeListBoxLabel        matlab.ui.control.Label
        CurrentRangeListBoxLabel        matlab.ui.control.Label
        VoltageRangeListBoxLabel        matlab.ui.control.Label
        AutoZeroSwitch                  matlab.ui.control.Switch
        AutoZeroSwitchLabel             matlab.ui.control.Label
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentLimitEditField           matlab.ui.control.NumericEditField
        CurrentLimitEditFieldLabel      matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        LevelVoltageEditField           matlab.ui.control.NumericEditField
        LevelVoltageEditFieldLabel      matlab.ui.control.Label
        ItKeithleyModeBiasLabel         matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        DelaySTextArea                  matlab.ui.control.TextArea
        DelaySTextAreaLabel             matlab.ui.control.Label
        VoltageVTextArea                matlab.ui.control.TextArea
        VoltageVTextAreaLabel           matlab.ui.control.Label
        SweepPointsTextArea             matlab.ui.control.TextArea
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        MeasurementWindowLabel          matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        SLabel                          matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        It_Plot_Linear                  matlab.ui.control.UIAxes
        It_Plot_Logarithmic             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;
        
        data;
        sheet1;
        sheet2;
        settings;
        measurement_table;
        measurement_var_names;
        consttableData;
        defaultFolder;
            
        i;
        time;
        voltage_source;
        current_measure;

        KLYSM2450;
        connectionStatusKeithley;
        freq;
        aftervoltage;
 
        LevelVoltage;
        Voltagerange;
        Currentlimit;

        Currentrange;
        Minimumrange
        Autozero;
        Inputterminals;
        Outputoff;
        Sense;

        Sweeppoints;
        CurrentRange;
        SourcetoMeasureDelay;
        measurement_window;
        NPLC;
        Measurecount;
        updateInterval;
        stopExperiment;

    end

    methods (Access = public)
        function connectDeviceKeithley(app)
            itKeithley4ConnectDevice(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath(fullfile(fileparts(mfilename('fullpath')), 'dependencies'));
            itKeithley4Startup(app);

        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            
            itKeithley4StartExperiment(app);

        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            
            itKeithley4StopExperiment(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            itKeithley4CloseRequest(app);

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            
            itKeithley4SaveData(app);
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            
            itKeithley4SelectFolder(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CurrentVoltageItKeithleyTab = uitab(app.TabGroup2);
            app.CurrentVoltageItKeithleyTab.Title = 'Current-Voltage (I-t, Keithley)';
            app.TabGroup = uitabgroup(app.CurrentVoltageItKeithleyTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ItKeithleyModeBiasLabel = uilabel(app.SettingsTab);
            app.ItKeithleyModeBiasLabel.Text = 'I-t / Keithley, Mode: Bias';
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.LevelVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.LevelVoltageEditFieldLabel.Text = 'Level Voltage';
            app.LevelVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.LevelVoltageEditField.Value = -1;
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_6.Text = '[Volts]';
            app.CurrentLimitEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentLimitEditFieldLabel.Text = 'Current Limit';
            app.CurrentLimitEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.CurrentLimitEditField.Value = 0.1;
            app.AmpsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.AmpsLabel_2.Text = '[Amps]';
            app.AutoZeroSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.AutoZeroSwitchLabel.Text = 'Auto Zero';
            app.AutoZeroSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.VoltageRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.VoltageRangeListBoxLabel.Text = 'Voltage Range';
            app.CurrentRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentRangeListBoxLabel.Text = 'Current Range';
            app.MinimumRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.MinimumRangeListBoxLabel.Text = 'Minimum Range';
            app.VoltageRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.VoltageRangeListBox.Items = {'Auto'};
            app.VoltageRangeListBox.Value = 'Auto';
            app.CurrentRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.CurrentRangeListBox.Items = {'Auto'};
            app.CurrentRangeListBox.Value = 'Auto';
            app.MinimumRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.MinimumRangeListBox.Items = {'10 nA'};
            app.MinimumRangeListBox.Value = '10 nA';
            app.OutputOffListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.OutputOffListBoxLabel.Text = 'Output Off';
            app.InputTerminalsListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.InputTerminalsListBoxLabel.Text = 'Input Terminals';
            app.SenseListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.SenseListBoxLabel.Text = 'Sense';
            app.OutputOffListBox = uilistbox(app.MeasurementParametersPanel);
            app.OutputOffListBox.Items = {'Normal'};
            app.OutputOffListBox.Value = 'Normal';
            app.InputTerminalsListBox = uilistbox(app.MeasurementParametersPanel);
            app.InputTerminalsListBox.Items = {'Rear', 'Front'};
            app.InputTerminalsListBox.Value = 'Rear';
            app.SenseListBox = uilistbox(app.MeasurementParametersPanel);
            app.SenseListBox.Items = {'2-Wire', '4-Wire'};
            app.SenseListBox.Value = '2-Wire';
            app.CommonSettingsPanel = uipanel(app.SettingsTab);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.NPLCEditField_2Label = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.NPLCEditField.Value = 0.1;
            app.NPLCEditField_2Label_2 = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.SourceSweepPointsLabel = uilabel(app.CommonSettingsPanel);
            app.SourceSweepPointsLabel.Text = 'Source / Sweep Points';
            app.SourceSweepPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourceSweepPointsEditField.Limits = [2 Inf];
            app.SourceSweepPointsEditField.Value = 21;
            app.SourcetoMeasureDelaySEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.SourcetoMeasureDelaySEditFieldLabel.Text = {'Source to Measure Delay'; '[S]'};
            app.SourcetoMeasureDelaySEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditField.Value = 0.1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.CommonSettingsPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.CommonSettingsPanel, 'slider');
            app.UpdateIntervalEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.UpdateIntervalEditFieldLabel.Text = 'Update Interval';
            app.UpdateIntervalEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.UpdateIntervalEditField.Value = 1;
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.KEITHLEYSourceMeter2450LampLabel = uilabel(app.ConnectionStatusPanel);
            app.KEITHLEYSourceMeter2450LampLabel.Text = 'KEITHLEY SourceMeter 2450';
            app.KEITHLEYSourceMeter2450Lamp = uilamp(app.ConnectionStatusPanel);
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.SelectFolderButton = uibutton(app.SettingsTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.DefaultFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultFolderEditFieldLabel.Text = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.SettingsTab, 'text');
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Time [S]'; 'Current [A]'; 'Voltage [V]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'NPLC'};
            app.ConstantValuesTabel.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.It_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.It_Plot_Logarithmic, 'Logarithmic I-t Characteristics')
            xlabel(app.It_Plot_Logarithmic, 'Time [S]')
            ylabel(app.It_Plot_Logarithmic, 'Logarithmic Current [A]')
            zlabel(app.It_Plot_Logarithmic, 'Z')
            app.It_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.It_Plot_Linear, 'Linear I-t Characteristics')
            xlabel(app.It_Plot_Linear, 'Time [S]')
            ylabel(app.It_Plot_Linear, 'Linear Current [A]')
            zlabel(app.It_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Time';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.TimeTextArea = uitextarea(app.MeasurementsPanel);
            app.TimeTextArea.Editable = 'off';
            app.SLabel = uilabel(app.MeasurementsPanel);
            app.SLabel.Text = '[S]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.VoltageVTextAreaLabel = uilabel(app.ConstantsPanel);
            app.VoltageVTextAreaLabel.Text = 'Voltage, V';
            app.VoltageVTextArea = uitextarea(app.ConstantsPanel);
            app.VoltageVTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CurrentVoltageItKeithleyTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CurrentVoltageItKeithleyTab);
            app.StopExperimentButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = It_Keithley_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end